classdef ReactorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NoIdealReactorButton            matlab.ui.control.Button
        ChoosethekindofproblemtobesolvedListBox  matlab.ui.control.ListBox
        ChoosethekindofproblemtobesolvedListBoxLabel  matlab.ui.control.Label
        OptimLocusPannel                matlab.ui.container.Panel
        ReactorLocus                    matlab.ui.control.EditField
        ReactorEditFieldLabel_8         matlab.ui.control.Label
        MinVolumeLocus                  matlab.ui.control.NumericEditField
        MinimumreactorvolumeEditFieldLabel  matlab.ui.control.Label
        MinTimeLocus                    matlab.ui.control.NumericEditField
        MinimumreactiontimeEditFieldLabel  matlab.ui.control.Label
        CheckLocusReactor               matlab.ui.control.CheckBox
        CheckLocusReaction              matlab.ui.control.CheckBox
        ReactiveSystemLocus             matlab.ui.control.EditField
        ReactiveSystemLabel_4           matlab.ui.control.Label
        KeyIndexLocus                   matlab.ui.control.NumericEditField
        KeycomponentindexLabel          matlab.ui.control.Label
        FeedStreamLocus                 matlab.ui.control.EditField
        FeedStreamEditFieldLabel_10     matlab.ui.control.Label
        ProductStreamLocus              matlab.ui.control.EditField
        ProductStreamLabel_4            matlab.ui.control.Label
        RunLocus                        matlab.ui.control.Button
        Image6                          matlab.ui.control.Image
        Image2_5                        matlab.ui.control.Image
        OptimPannel                     matlab.ui.container.Panel
        TypeOfObjectiveFcn              matlab.ui.container.ButtonGroup
        MinimizecostButton              matlab.ui.control.RadioButton
        MinimizeresidencetimeButton     matlab.ui.control.RadioButton
        SolutionLabelEditField          matlab.ui.control.NumericEditField
        SolutionLabelEditFieldLabel     matlab.ui.control.Label
        OptimumsizeEditField            matlab.ui.control.NumericEditField
        OptimumsizeEditFieldLabel       matlab.ui.control.Label
        FeedStreamMin                   matlab.ui.control.EditField
        FeedStreamEditFieldLabel_9      matlab.ui.control.Label
        ProductStreamMin                matlab.ui.control.EditField
        ProductStreamLabel_3            matlab.ui.control.Label
        ReactorMin                      matlab.ui.control.EditField
        ReactorEditFieldLabel_7         matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        RunMin                          matlab.ui.control.Button
        ReactiveSystemMin               matlab.ui.control.EditField
        ReactiveSystemLabel_3           matlab.ui.control.Label
        Image2_4                        matlab.ui.control.Image
        RecyclePannel                   matlab.ui.container.Panel
        CostCheckBoxRec                 matlab.ui.control.CheckBox
        CostEditFieldRec                matlab.ui.control.NumericEditField
        TACyearLabel_2                  matlab.ui.control.Label
        RecycleRatio                    matlab.ui.control.NumericEditField
        RecycleRatioEditFieldLabel      matlab.ui.control.Label
        RunRecycle                      matlab.ui.control.Button
        FeedStreamRecycle               matlab.ui.control.EditField
        FeedStreamEditFieldLabel_8      matlab.ui.control.Label
        ReactorRecycle                  matlab.ui.control.EditField
        ReactorEditFieldLabel_6         matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        ReactiveSystemRecycle           matlab.ui.control.EditField
        ReactiveSystemEditFieldLabel_6  matlab.ui.control.Label
        Image2_3                        matlab.ui.control.Image
        AssociationPannel               matlab.ui.container.Panel
        CostEditFieldAssoc              matlab.ui.control.NumericEditField
        TACyearLabel_3                  matlab.ui.control.Label
        CostCheckBoxAssoc               matlab.ui.control.CheckBox
        ReactiveSystemAssociation       matlab.ui.control.EditField
        ReactiveSystemEditFieldLabel_7  matlab.ui.control.Label
        ButtonAssociation               matlab.ui.container.ButtonGroup
        ParallelButton                  matlab.ui.control.RadioButton
        SeriesButton                    matlab.ui.control.RadioButton
        SplitAssociation                matlab.ui.control.EditField
        SplitoptionalLabel              matlab.ui.control.Label
        FeedStreamAssociation           matlab.ui.control.EditField
        FeedStreamEditFieldLabel_7      matlab.ui.control.Label
        SequenceAssociation             matlab.ui.control.EditField
        SequenceofreactorsLabel         matlab.ui.control.Label
        RunAssociation                  matlab.ui.control.Button
        Image2_2                        matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        SingleReactorPanel              matlab.ui.container.Panel
        CostEditField                   matlab.ui.control.NumericEditField
        TACyearLabel                    matlab.ui.control.Label
        CostCheckBox                    matlab.ui.control.CheckBox
        RunIndividual                   matlab.ui.control.Button
        ReactiveSystemIndividual        matlab.ui.control.EditField
        ReactiveSystemEditFieldLabel_4  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        FeedStreamIndividual            matlab.ui.control.EditField
        FeedStreamEditFieldLabel_6      matlab.ui.control.Label
        ReactorIndividual               matlab.ui.control.EditField
        ReactorEditFieldLabel_5         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ProvideinitialdataPanel         matlab.ui.container.Panel
        CreateReactor                   matlab.ui.control.Button
        CreateStream                    matlab.ui.control.Button
        CreateReactiveSystem            matlab.ui.control.Button
        MainWindowLabel                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        solutionStream = Stream ;
        solutionStreamDispayWindow ;
        feedStream
        reactorObject
        reactionSysObject
    end
    
    methods (Access = private)
        
        function displaySolutionWindow(app)
            command2 = 'save solutionVariablesFile.mat ProductData';
            evalin('base',command2)
            load('solutionVariablesFile.mat','ProductData') ;
            app.solutionStreamDispayWindow = defineStreamApp(ProductData) ;
        end
        
        
        function manageVisibilityPannels(app,state)
            app.SingleReactorPanel.Visible = state{1} ;
            app.AssociationPannel.Visible = state{2} ;
            app.RecyclePannel.Visible = state{3} ;
            app.OptimPannel.Visible = state{4} ;
            app.OptimLocusPannel.Visible = state{5} ;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % evalin("base",'clear all')
        end

        % Button pushed function: RunIndividual
        function RunIndividualPushed(app, event)
            R  = app.ReactorIndividual.Value ;
            F  = app.FeedStreamIndividual.Value ;
            RS = app.ReactiveSystemIndividual.Value ;
            
            % Ejecutar la función compute_output.
            % PROBLEMA: solución se queda en el Workspace, no en la app
            command = string(['[ProductData,',R,']= compute_output(',R,',',F,',',RS,');']) ;
            evalin('base',command)
            
            % SOLUCIÓN: Guardar la variable que contiene la solución para poder cargarla en la app
            app.displaySolutionWindow
            
            % Cost evaluation
            if app.CostCheckBox.Value
                costCommand = string(['TAC = computeCost(',R,',',F,',ProductData);']) ;
                evalin('base',costCommand) ;
                
                saveCommand = 'save costFile.mat TAC';
                evalin('base',saveCommand)
                load('costFile.mat','TAC') ;
                app.CostEditField.Value = TAC ;
                delete("costFile.mat")
                
                app.CostEditField.Visible = 'on' ;
                app.TACyearLabel.Visible = 'on' ;
            end
            
        end

        % Button pushed function: RunAssociation
        function RunAssociationButtonPushed(app, event)
            evalin('base','auxReactorObj = Reactor ;')
            sequence  = app.SequenceAssociation.Value ;
            F  = app.FeedStreamAssociation.Value ;
            RS = app.ReactiveSystemAssociation.Value ;
            switch app.ButtonAssociation.SelectedObject.Text
                case 'Series'
                    command = string(['[ProductData,sequence] = compute_series(auxReactorObj,',F,',',RS,',{',sequence,'});']) ;
                case 'Parallel'
                    if isempty(app.SplitAssociation.Value)
                        command = string(['[ProductData,sequence]  = compute_parallel(auxReactorObj,',F,',',RS,',{',sequence,'});']) ;
                    else
                        command = string(['[ProductData,sequence]  = compute_parallel(auxReactorObj,',F,',',RS,',{',sequence,'},',app.SplitAssociation.Value,');']) ;
                    end
            end
            evalin('base',command)
            app.displaySolutionWindow
            
            % Cost evaluation
            if app.CostCheckBoxAssoc.Value
                sequence = split(sequence,',') ;
                indvCost = zeros(size(sequence)) ;

                for i = 1:length(sequence)
                    costCommand = sprintf('TAC = computeCost(sequence{%.0f},%s,ProductData);',i,F) ;
                    evalin('base',costCommand) ;
                    
                    saveCommand = 'save costFile.mat TAC';
                    evalin('base',saveCommand)
                    load('costFile.mat','TAC') ;
                    indvCost(i) = TAC ;
                    delete("costFile.mat")
                end
                
                app.CostEditFieldAssoc.Value = sum(indvCost);
                app.CostEditFieldAssoc.Visible = 'on' ;
                app.TACyearLabel_3.Visible = 'on' ;
            end
        end

        % Selection changed function: ButtonAssociation
        function ButtonAssociationSelectionChanged(app, event)
            selectedButton = app.ButtonAssociation.SelectedObject;
            switch selectedButton.Text
                case 'Series'
                    app.SplitoptionalLabel.Visible = 'off' ;
                    app.SplitAssociation.Visible = 'off' ;
                case 'Parallel'
                    app.SplitoptionalLabel.Visible = 'on' ;
                    app.SplitAssociation.Visible = 'on' ;
            end
        end

        % Button pushed function: RunRecycle
        function RunRecycleButtonPushed(app, event)
            R  = app.ReactorRecycle.Value ;
            F  = app.FeedStreamRecycle.Value ;
            RS = app.ReactiveSystemRecycle.Value ;
            recycleRatio = num2str(app.RecycleRatio.Value) ;
            
            command = string(['[ProductData,',R,'] = compute_recycling(',R,',',F,',',RS,',',recycleRatio,');']) ;
            evalin('base',command)
            app.displaySolutionWindow
            
            % Cost evaluation
            if app.CostCheckBoxRec.Value
                costCommand = string(['TAC = computeCost(',R,',',F,',ProductData);']) ;
                evalin('base',costCommand) ;
                
                saveCommand = 'save costFile.mat TAC';
                evalin('base',saveCommand)
                load('costFile.mat','TAC') ;
                app.CostEditFieldRec.Value = TAC ;
                delete("costFile.mat")
                
                app.CostEditFieldRec.Visible = 'on' ;
                app.TACyearLabel_2.Visible = 'on' ;
            end
            
        end

        % Button pushed function: RunMin
        function RunMinButtonPushed(app, event)
            R  = app.ReactorMin.Value ;
            F  = app.FeedStreamMin.Value ;
            P  = app.ProductStreamMin.Value ;
            RS = app.ReactiveSystemMin.Value ;
            
            switch app.TypeOfObjectiveFcn.SelectedObject.Text
                case 'Minimize residence time'
                    command = string(['[optimSize,optimObjFcn] = minimize_residenceTime(',R,',',F,',',RS,',',P,');']) ;
                    app.SolutionLabelEditFieldLabel.Text = 'Minimum residence time' ;
                case 'Minimize cost'
                    command = string(['[optimSize,optimObjFcn] = minimize_cost(',R,',',F,',',RS,',',P,');']) ;
                    app.SolutionLabelEditFieldLabel.Text = 'Minimum cost' ;
            end
            evalin('base',command)
            
            command2 = 'save solutionVariablesFile.mat optimSize optimObjFcn' ;
            evalin('base',command2)
            load('solutionVariablesFile.mat','optimSize','optimObjFcn') ;
            
            app.OptimumsizeEditField.Visible = 'on' ;
            app.OptimumsizeEditFieldLabel.Visible = 'on' ;
            app.OptimumsizeEditField.Value = optimSize ;
            
            app.SolutionLabelEditField.Visible = 'on' ;
            app.SolutionLabelEditFieldLabel.Visible = 'on' ;
            app.SolutionLabelEditField.Value = optimObjFcn ;
            
            delete("solutionVariablesFile.mat")
            
        end

        % Value changed function: CheckLocusReaction, CheckLocusReactor
        function CheckLocusReactionValueChanged(app, event)
            valueReaction = app.CheckLocusReaction.Value;
            valueReactor  = app.CheckLocusReactor.Value ;
            if valueReactor + valueReaction == 1
                app.RunLocus.BackgroundColor = [1.00,1.00,0.00] ; %yellow
                app.RunLocus.Visible = 'on' ;
            elseif valueReactor && valueReaction
                app.RunLocus.BackgroundColor = [0.00,1.00,0.00] ; %green
                app.RunLocus.Visible = 'on' ;
            else
                app.RunLocus.Visible = 'off' ;
            end
        end

        % Button pushed function: RunLocus
        function RunLocusButtonPushed(app, event)
            R  = app.ReactorLocus.Value ;
            F  = app.FeedStreamLocus.Value ;
            P  = app.ProductStreamLocus.Value ;
            RS = app.ReactiveSystemLocus.Value ;
            keyIndex = num2str(app.KeyIndexLocus.Value) ;
            command = string(['[minimumTime,minimumVolume] = find_optimalTemperaturePath(',F,',',P,',',R,',',RS,',',keyIndex,');']) ;
            evalin('base',command)
            
            command2 = 'save solutionVariablesFile.mat minimumTime minimumVolume' ;
            evalin('base',command2)
            load('solutionVariablesFile.mat','minimumTime','minimumVolume') ;
            app.MinTimeLocus.Value = minimumTime ;
            app.MinVolumeLocus.Value = minimumVolume ;
            delete("solutionVariablesFile.mat")
            
            % Cost evaluation
            if app.CostCheckBoxLocus.Value
                costCommand = string(['CBM = estimateCost(',R,',',F,'.P);']) ;
                evalin('base',costCommand) ;
                
                saveCommand = 'save costFile.mat CBM';
                evalin('base',saveCommand)
                load('costFile.mat','CBM') ;
                app.CostEditFieldLocus.Value = CBM ;
                delete("costFile.mat")
            end
        end

        % Button pushed function: CreateReactiveSystem
        function CreateReactiveSystemButtonPushed(app, event)
            defineReactionSysApp
        end

        % Button pushed function: CreateStream
        function CreateStreamButtonPushed(app, event)
            defineStreamApp
        end

        % Button pushed function: CreateReactor
        function CreateReactorButtonPushed(app, event)
            defineReactorApp
        end

        % Value changed function: ChoosethekindofproblemtobesolvedListBox
        function ChoosethekindofproblemtobesolvedListBoxValueChanged(app, event)
            value = app.ChoosethekindofproblemtobesolvedListBox.Value;
            switch value
                case 'Simulate single reactors'
                    state = {'on','off','off','off','off'} ;
                case 'Simulate an association of reactors'
                    state = {'off','on','off','off','off'} ;
                case 'Simulate a reactor with recycling'
                    state = {'off','off','on','off','off'} ;
                case 'Optimize reactor'
                    state = {'off','off','off','on','off'} ;
                case 'Minimize residence time (exothermic reversible reactions)'
                    state = {'off','off','off','off','on'} ;
                case ''
                    state = {'off','off','off','off','off'} ;
            end
            app.manageVisibilityPannels(state) ;
        end

        % Button pushed function: NoIdealReactorButton
        function NoIdealReactorButtonPushed(app, event)
            NonIdealReactorApp();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MainWindowLabel = uilabel(app.UIFigure);
            app.MainWindowLabel.Text = 'Main Window';
            app.ProvideinitialdataPanel = uipanel(app.UIFigure);
            app.ProvideinitialdataPanel.Title = 'Provide initial data';
            app.CreateReactiveSystem = uibutton(app.ProvideinitialdataPanel, 'push');
            app.CreateReactiveSystem.ButtonPushedFcn = createCallbackFcn(app, @CreateReactiveSystemButtonPushed, true);
            app.CreateReactiveSystem.Text = 'Reactive System';
            app.CreateStream = uibutton(app.ProvideinitialdataPanel, 'push');
            app.CreateStream.ButtonPushedFcn = createCallbackFcn(app, @CreateStreamButtonPushed, true);
            app.CreateStream.Text = 'Stream';
            app.CreateReactor = uibutton(app.ProvideinitialdataPanel, 'push');
            app.CreateReactor.ButtonPushedFcn = createCallbackFcn(app, @CreateReactorButtonPushed, true);
            app.CreateReactor.Text = 'Reactor';
            app.SingleReactorPanel = uipanel(app.UIFigure);
            app.SingleReactorPanel.Visible = 'off';
            app.Image = uiimage(app.SingleReactorPanel);
            app.Image.ImageSource = 'ImageSingleReactor.png';
            app.ReactorEditFieldLabel_5 = uilabel(app.SingleReactorPanel);
            app.ReactorEditFieldLabel_5.Text = 'Reactor';
            app.ReactorIndividual = uieditfield(app.SingleReactorPanel, 'text');
            app.FeedStreamEditFieldLabel_6 = uilabel(app.SingleReactorPanel);
            app.FeedStreamEditFieldLabel_6.Text = 'Feed Stream';
            app.FeedStreamIndividual = uieditfield(app.SingleReactorPanel, 'text');
            app.Image2 = uiimage(app.SingleReactorPanel);
            app.Image2.ImageSource = 'ImageMolecule.png';
            app.ReactiveSystemEditFieldLabel_4 = uilabel(app.SingleReactorPanel);
            app.ReactiveSystemEditFieldLabel_4.Text = 'Reactive System';
            app.ReactiveSystemIndividual = uieditfield(app.SingleReactorPanel, 'text');
            app.RunIndividual = uibutton(app.SingleReactorPanel, 'push');
            app.RunIndividual.ButtonPushedFcn = createCallbackFcn(app, @RunIndividualPushed, true);
            app.RunIndividual.Text = 'Run';
            app.CostCheckBox = uicheckbox(app.SingleReactorPanel);
            app.CostCheckBox.Text = 'Evaluate equipment costs';
            app.TACyearLabel = uilabel(app.SingleReactorPanel);
            app.TACyearLabel.Visible = 'off';
            app.TACyearLabel.Text = 'TAC ($/year)';
            app.CostEditField = uieditfield(app.SingleReactorPanel, 'numeric');
            app.CostEditField.Visible = 'off';
            app.AssociationPannel = uipanel(app.UIFigure);
            app.AssociationPannel.Visible = 'off';
            app.Image3 = uiimage(app.AssociationPannel);
            app.Image3.ImageSource = 'ImageAsocReactors.png';
            app.Image2_2 = uiimage(app.AssociationPannel);
            app.Image2_2.ImageSource = 'ImageMolecule.png';
            app.RunAssociation = uibutton(app.AssociationPannel, 'push');
            app.RunAssociation.ButtonPushedFcn = createCallbackFcn(app, @RunAssociationButtonPushed, true);
            app.RunAssociation.Text = 'Run';
            app.SequenceofreactorsLabel = uilabel(app.AssociationPannel);
            app.SequenceofreactorsLabel.Text = 'Sequence of reactors';
            app.SequenceAssociation = uieditfield(app.AssociationPannel, 'text');
            app.SequenceAssociation.Tooltip = {'Express the reactors to be associated with their respective names and separated with commas.'; 'Example : Reactor1,Reactor2,...,ReactorN'};
            app.FeedStreamEditFieldLabel_7 = uilabel(app.AssociationPannel);
            app.FeedStreamEditFieldLabel_7.Text = 'Feed Stream';
            app.FeedStreamAssociation = uieditfield(app.AssociationPannel, 'text');
            app.SplitoptionalLabel = uilabel(app.AssociationPannel);
            app.SplitoptionalLabel.Text = 'Split (optional)';
            app.SplitAssociation = uieditfield(app.AssociationPannel, 'text');
            app.SplitAssociation.Tooltip = {'Specify the % of the feed stream that enters each reactor as a vector.'; 'WARNING: the number of elements must coincide with the number of reactors.'; 'EXAMPLE:'; 'Sequence of reactors    Reactor1,Reactor2,Reactor3'; 'Split                               [ 0.5 , 0.25 , 0.25 ]'; '(*) If split is not specified, it is assumed that feed stream splits equally to each reactor.'};
            app.ButtonAssociation = uibuttongroup(app.AssociationPannel);
            app.ButtonAssociation.SelectionChangedFcn = createCallbackFcn(app, @ButtonAssociationSelectionChanged, true);
            app.SeriesButton = uiradiobutton(app.ButtonAssociation);
            app.SeriesButton.Text = 'Series';
            app.ParallelButton = uiradiobutton(app.ButtonAssociation);
            app.ParallelButton.Text = 'Parallel';
            app.ParallelButton.Value = true;
            app.ReactiveSystemEditFieldLabel_7 = uilabel(app.AssociationPannel);
            app.ReactiveSystemEditFieldLabel_7.Text = 'Reactive System';
            app.ReactiveSystemAssociation = uieditfield(app.AssociationPannel, 'text');
            app.CostCheckBoxAssoc = uicheckbox(app.AssociationPannel);
            app.CostCheckBoxAssoc.Text = 'Evaluate equipment cost';
            app.TACyearLabel_3 = uilabel(app.AssociationPannel);
            app.TACyearLabel_3.Visible = 'off';
            app.TACyearLabel_3.Text = 'TAC ($/year)';
            app.CostEditFieldAssoc = uieditfield(app.AssociationPannel, 'numeric');
            app.CostEditFieldAssoc.Visible = 'off';
            app.RecyclePannel = uipanel(app.UIFigure);
            app.RecyclePannel.Visible = 'off';
            app.Image2_3 = uiimage(app.RecyclePannel);
            app.Image2_3.ImageSource = 'ImageMolecule.png';
            app.ReactiveSystemEditFieldLabel_6 = uilabel(app.RecyclePannel);
            app.ReactiveSystemEditFieldLabel_6.Text = 'Reactive System';
            app.ReactiveSystemRecycle = uieditfield(app.RecyclePannel, 'text');
            app.Image4 = uiimage(app.RecyclePannel);
            app.Image4.ImageSource = 'ImageRecycleReactor.png';
            app.ReactorEditFieldLabel_6 = uilabel(app.RecyclePannel);
            app.ReactorEditFieldLabel_6.Text = 'Reactor';
            app.ReactorRecycle = uieditfield(app.RecyclePannel, 'text');
            app.FeedStreamEditFieldLabel_8 = uilabel(app.RecyclePannel);
            app.FeedStreamEditFieldLabel_8.Text = 'Feed Stream';
            app.FeedStreamRecycle = uieditfield(app.RecyclePannel, 'text');
            app.RunRecycle = uibutton(app.RecyclePannel, 'push');
            app.RunRecycle.ButtonPushedFcn = createCallbackFcn(app, @RunRecycleButtonPushed, true);
            app.RunRecycle.Text = 'Run';
            app.RecycleRatioEditFieldLabel = uilabel(app.RecyclePannel);
            app.RecycleRatioEditFieldLabel.Text = 'Recycle Ratio';
            app.RecycleRatio = uieditfield(app.RecyclePannel, 'numeric');
            app.RecycleRatio.Limits = [0 Inf];
            app.RecycleRatio.Tooltip = {'Recycle Ratio = (moles recycle stream)/(moles leaving the system)'; ''; 'NOTICE that moles outlet reactor = moles recycle stream + moles leaving system'};
            app.TACyearLabel_2 = uilabel(app.RecyclePannel);
            app.TACyearLabel_2.Visible = 'off';
            app.TACyearLabel_2.Text = 'TAC ($/year)';
            app.CostEditFieldRec = uieditfield(app.RecyclePannel, 'numeric');
            app.CostEditFieldRec.Visible = 'off';
            app.CostCheckBoxRec = uicheckbox(app.RecyclePannel);
            app.CostCheckBoxRec.Text = 'Evaluate equipment cost';
            app.OptimPannel = uipanel(app.UIFigure);
            app.OptimPannel.Visible = 'off';
            app.Image2_4 = uiimage(app.OptimPannel);
            app.Image2_4.ImageSource = 'ImageMolecule.png';
            app.ReactiveSystemLabel_3 = uilabel(app.OptimPannel);
            app.ReactiveSystemLabel_3.Text = 'Reactive System';
            app.ReactiveSystemMin = uieditfield(app.OptimPannel, 'text');
            app.RunMin = uibutton(app.OptimPannel, 'push');
            app.RunMin.ButtonPushedFcn = createCallbackFcn(app, @RunMinButtonPushed, true);
            app.RunMin.Text = 'Run';
            app.Image_2 = uiimage(app.OptimPannel);
            app.Image_2.ImageSource = 'ImageSingleReactor.png';
            app.ReactorEditFieldLabel_7 = uilabel(app.OptimPannel);
            app.ReactorEditFieldLabel_7.Text = 'Reactor';
            app.ReactorMin = uieditfield(app.OptimPannel, 'text');
            app.ProductStreamLabel_3 = uilabel(app.OptimPannel);
            app.ProductStreamLabel_3.Text = 'Product Stream';
            app.ProductStreamMin = uieditfield(app.OptimPannel, 'text');
            app.FeedStreamEditFieldLabel_9 = uilabel(app.OptimPannel);
            app.FeedStreamEditFieldLabel_9.Text = 'Feed Stream';
            app.FeedStreamMin = uieditfield(app.OptimPannel, 'text');
            app.OptimumsizeEditFieldLabel = uilabel(app.OptimPannel);
            app.OptimumsizeEditFieldLabel.Visible = 'off';
            app.OptimumsizeEditFieldLabel.Text = 'Optimum size';
            app.OptimumsizeEditField = uieditfield(app.OptimPannel, 'numeric');
            app.OptimumsizeEditField.Visible = 'off';
            app.SolutionLabelEditFieldLabel = uilabel(app.OptimPannel);
            app.SolutionLabelEditFieldLabel.Visible = 'off';
            app.SolutionLabelEditFieldLabel.Text = 'Solution Label';
            app.SolutionLabelEditField = uieditfield(app.OptimPannel, 'numeric');
            app.SolutionLabelEditField.Visible = 'off';
            app.TypeOfObjectiveFcn = uibuttongroup(app.OptimPannel);
            app.MinimizeresidencetimeButton = uiradiobutton(app.TypeOfObjectiveFcn);
            app.MinimizeresidencetimeButton.Text = 'Minimize residence time';
            app.MinimizeresidencetimeButton.Value = true;
            app.MinimizecostButton = uiradiobutton(app.TypeOfObjectiveFcn);
            app.MinimizecostButton.Text = 'Minimize cost';
            app.OptimLocusPannel = uipanel(app.UIFigure);
            app.OptimLocusPannel.Visible = 'off';
            app.Image2_5 = uiimage(app.OptimLocusPannel);
            app.Image2_5.ImageSource = 'ImageMolecule.png';
            app.Image6 = uiimage(app.OptimLocusPannel);
            app.Image6.ImageSource = 'ImageSingleReactor.png';
            app.RunLocus = uibutton(app.OptimLocusPannel, 'push');
            app.RunLocus.ButtonPushedFcn = createCallbackFcn(app, @RunLocusButtonPushed, true);
            app.RunLocus.Visible = 'off';
            app.RunLocus.Text = 'Run';
            app.ProductStreamLabel_4 = uilabel(app.OptimLocusPannel);
            app.ProductStreamLabel_4.Text = 'Product Stream';
            app.ProductStreamLocus = uieditfield(app.OptimLocusPannel, 'text');
            app.FeedStreamEditFieldLabel_10 = uilabel(app.OptimLocusPannel);
            app.FeedStreamEditFieldLabel_10.Text = 'Feed Stream';
            app.FeedStreamLocus = uieditfield(app.OptimLocusPannel, 'text');
            app.KeycomponentindexLabel = uilabel(app.OptimLocusPannel);
            app.KeycomponentindexLabel.Text = 'Key component index';
            app.KeyIndexLocus = uieditfield(app.OptimLocusPannel, 'numeric');
            app.KeyIndexLocus.Limits = [0 Inf];
            app.KeyIndexLocus.ValueDisplayFormat = '%.0f';
            app.KeyIndexLocus.Tooltip = {'Enter the index of the key component.'; ''; 'EXAMPLE: '; '2A + B <-> C'; 'B is the key component.'; 'When the stochiometric matrix was specified, B is always in column 2. Then, '; 'Key Component Index = 2 '};
            app.ReactiveSystemLabel_4 = uilabel(app.OptimLocusPannel);
            app.ReactiveSystemLabel_4.Text = 'Reactive System';
            app.ReactiveSystemLocus = uieditfield(app.OptimLocusPannel, 'text');
            app.CheckLocusReaction = uicheckbox(app.OptimLocusPannel);
            app.CheckLocusReaction.ValueChangedFcn = createCallbackFcn(app, @CheckLocusReactionValueChanged, true);
            app.CheckLocusReaction.Text = 'I know this function is only valid for SINGLE, REVERSIBLE EXOTHERMIC reactions';
            app.CheckLocusReactor = uicheckbox(app.OptimLocusPannel);
            app.CheckLocusReactor.ValueChangedFcn = createCallbackFcn(app, @CheckLocusReactionValueChanged, true);
            app.CheckLocusReactor.Text = 'I know ADIABATIC REACTORS are NOT supported in this function';
            app.MinimumreactiontimeEditFieldLabel = uilabel(app.OptimLocusPannel);
            app.MinimumreactiontimeEditFieldLabel.Text = 'Minimum reaction time';
            app.MinTimeLocus = uieditfield(app.OptimLocusPannel, 'numeric');
            app.MinimumreactorvolumeEditFieldLabel = uilabel(app.OptimLocusPannel);
            app.MinimumreactorvolumeEditFieldLabel.Text = 'Minimum reactor volume';
            app.MinVolumeLocus = uieditfield(app.OptimLocusPannel, 'numeric');
            app.ReactorEditFieldLabel_8 = uilabel(app.OptimLocusPannel);
            app.ReactorEditFieldLabel_8.Text = 'Reactor';
            app.ReactorLocus = uieditfield(app.OptimLocusPannel, 'text');
            app.ChoosethekindofproblemtobesolvedListBoxLabel = uilabel(app.UIFigure);
            app.ChoosethekindofproblemtobesolvedListBoxLabel.Text = 'Choose the kind of problem to be solved';
            app.ChoosethekindofproblemtobesolvedListBox = uilistbox(app.UIFigure);
            app.ChoosethekindofproblemtobesolvedListBox.Items = {'', 'Simulate single reactors', 'Simulate an association of reactors', 'Simulate a reactor with recycling', 'Optimize reactor', 'Minimize residence time (exothermic reversible reactions)'};
            app.ChoosethekindofproblemtobesolvedListBox.ValueChangedFcn = createCallbackFcn(app, @ChoosethekindofproblemtobesolvedListBoxValueChanged, true);
            app.ChoosethekindofproblemtobesolvedListBox.Value = '';
            app.NoIdealReactorButton = uibutton(app.UIFigure, 'push');
            app.NoIdealReactorButton.ButtonPushedFcn = createCallbackFcn(app, @NoIdealReactorButtonPushed, true);
            app.NoIdealReactorButton.Text = 'No Ideal Reactor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReactorApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end