classdef defineReactorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ReactorLabel                 matlab.ui.control.Label
        ChooseanametoidentifythereactorLabel  matlab.ui.control.Label
        NameEditFieldLabel           matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        HeatexchangemodeButtonGroup  matlab.ui.container.ButtonGroup
        IsothermalButton             matlab.ui.control.RadioButton
        AdiabaticButton              matlab.ui.control.RadioButton
        OtherButton                  matlab.ui.control.RadioButton
        PressureDropMode             matlab.ui.container.ButtonGroup
        ConstantButton               matlab.ui.control.RadioButton
        NonconstantButton            matlab.ui.control.RadioButton
        VolumemEditFieldLabel        matlab.ui.control.Label
        VolumemEditField             matlab.ui.control.NumericEditField
        UnitsEditFieldLabel          matlab.ui.control.Label
        UnitsEditField               matlab.ui.control.EditField
        BypassRatioEditFieldLabel    matlab.ui.control.Label
        BypassRatioEditField         matlab.ui.control.NumericEditField
        LmEditFieldLabel             matlab.ui.control.Label
        LmEditField                  matlab.ui.control.NumericEditField
        NumberoftubesEditFieldLabel  matlab.ui.control.Label
        NumberoftubesEditField       matlab.ui.control.NumericEditField
        TubediametermEditFieldLabel  matlab.ui.control.Label
        TubediametermEditField       matlab.ui.control.NumericEditField
        DensitykgmLabel              matlab.ui.control.Label
        DensityEditField             matlab.ui.control.NumericEditField
        PorosityEditFieldLabel       matlab.ui.control.Label
        PorosityEditField            matlab.ui.control.NumericEditField
        UWmKLabel                    matlab.ui.control.Label
        UEditField                   matlab.ui.control.NumericEditField
        AmLabel                      matlab.ui.control.Label
        AEditField                   matlab.ui.control.NumericEditField
        TwinletKLabel                matlab.ui.control.Label
        TwEditField                  matlab.ui.control.NumericEditField
        QUATTwLabel                  matlab.ui.control.Label
        DisplayplotsSwitchLabel      matlab.ui.control.Label
        DisplayplotsSwitch           matlab.ui.control.Switch
        CreateReactorButton          matlab.ui.control.Button
        ReactiontimesLabel           matlab.ui.control.Label
        ReactiontimesEditField       matlab.ui.control.NumericEditField
        UnitsEditField_2Label        matlab.ui.control.Label
        UnitsEditField_2             matlab.ui.control.EditField
        TypeofreactorButtonGroup     matlab.ui.container.ButtonGroup
        PFRButton                    matlab.ui.control.RadioButton
        CSTRButton                   matlab.ui.control.RadioButton
        BatchButton                  matlab.ui.control.RadioButton
        HelperButton                 matlab.ui.control.RadioButton
        UtilitiesButton              matlab.ui.control.Button
        TwoutletKLabel               matlab.ui.control.Label
        TwOutletEditField            matlab.ui.control.NumericEditField
        UnitconversionhelperButton   matlab.ui.control.Button
        PressureDropEqn              matlab.ui.container.ButtonGroup
        PipeButton                   matlab.ui.control.RadioButton
        ErgunButton                  matlab.ui.control.RadioButton
        CatalystCheckBox             matlab.ui.control.CheckBox
        UtilitiesDataBaseLabel       matlab.ui.control.Label
        ParticlediametermEditFieldLabel  matlab.ui.control.Label
        ParticlediametermEditField   matlab.ui.control.NumericEditField
        CostkWyearEditFieldLabel     matlab.ui.control.Label
        CostUtilityEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        R % Reactor object
        DialogApp % Description
        UnitsApp
    end
    
    methods (Access = private)
        
        function visibilityPFRFeatures(app,state)
            app.LmEditField.Visible = state ;
            app.LmEditFieldLabel.Visible = state ;
            app.NumberoftubesEditField.Visible = state ;
            app.NumberoftubesEditFieldLabel.Visible = state ;
            app.TubediametermEditField.Visible = state;
            app.TubediametermEditFieldLabel.Visible = state ;
        end
        
        function visibilityHeatTransferFeatures(app,state)
            app.UEditField.Visible = state ;
            app.UWmKLabel.Visible = state ;
            app.AEditField.Visible = state ;
            app.AmLabel.Visible = state ;
            app.TwEditField.Visible = state ;
            app.TwinletKLabel.Visible = state ;
            app.UtilitiesButton.Visible = state ;
            app.QUATTwLabel.Visible = state ;
            app.UtilitiesDataBaseLabel.Visible = state ;
        end
        
        function visibilityBatchFeatures(app,state)
            app.ReactiontimesEditField.Visible = state ;
            app.ReactiontimesLabel.Visible = state ;
            %app.UnitsEditField_2.Visible = state ;
            %app.UnitsEditField_2Label.Visible = state ;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: TypeofreactorButtonGroup
        function TypeofreactorButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeofreactorButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'PFR'
                    app.visibilityPFRFeatures('on')
                    app.visibilityBatchFeatures('off')
                    if strcmp(app.PressureDropMode.SelectedObject.Text,'Non constant')
                        app.PressureDropEqn.Visible = 'on' ;
                    else
                        app.PressureDropEqn.Visible = 'off' ;
                    end
                case 'CSTR'
                    app.visibilityPFRFeatures('off')
                    app.visibilityBatchFeatures('off')
                    app.PressureDropEqn.Visible = 'off' ;
                case 'Batch'
                    app.visibilityPFRFeatures('off')
                    app.visibilityBatchFeatures('on')
                    app.PressureDropEqn.Visible = 'off' ;
            end
        end

        % Selection changed function: HeatexchangemodeButtonGroup
        function HeatexchangemodeButtonGroupSelectionChanged(app, event)
            selectedButton = app.HeatexchangemodeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Other'
                    app.visibilityHeatTransferFeatures('on')
                case {'Adiabatic','Isothermal'}
                    app.visibilityHeatTransferFeatures('off')
            end
        end

        % Button pushed function: CreateReactorButton
        function CreateReactorButtonPushed(app, event)
            if isempty(app.NameEditField.Value)
                msgbox('Name edit field is empty. Please, write an identifier before pushing "Create Reactor" button', 'Warning','warn');
            else
                selectedButton = app.TypeofreactorButtonGroup.SelectedObject;
                switch selectedButton.Text
                    case 'PFR'
                        app.R = PFR ;
                    case 'CSTR'
                        app.R = CSTR ;
                    case 'Batch'
                        app.R = Batch ;
                end
                
                if app.VolumemEditField.Value > 0
                    app.R.V = app.VolumemEditField.Value ;
                    app.R.V_Units = app.UnitsEditField.Value ;
                end
                
                app.R.bypassRatio = app.BypassRatioEditField.Value ;
                
                if app.ReactiontimesEditField.Value > 0
                    app.R.timeBatch = app.ReactiontimesEditField.Value ;
                    app.R.timeBatch_Units = app.UnitsEditField_2.Value ;
                end
                if app.LmEditField.Value > 0
                    app.R.L =app.LmEditField.Value ;
                    if app.TubediametermEditField.Value ~= 0.1
                        app.R.diameterTubes =app.TubediametermEditField.Value ;
                    end
                    if app.NumberoftubesEditField.Value > 1
                        app.R.nTubes = app.NumberoftubesEditField.Value ;
                    end
                end
                
                if app.CatalystCheckBox.Value
                    if app.DensityEditField.Value > 0
                        app.R.densityCatalyst = app.DensityEditField.Value ;
                    end
                    app.R.porosityCatalyst = app.PorosityEditField.Value ;
                end
                
                app.R.heatMode = app.HeatexchangemodeButtonGroup.SelectedObject.Text ;
                if strcmp(app.R.heatMode,'Other')
                    app.R.U = app.UEditField.Value ;
                    app.R.heatTransferArea = app.AEditField.Value ;
                    app.R.inletUtilityTemperature = app.TwEditField.Value ;
                    if strcmp(app.TwOutletEditField.Visible,'on')
                        app.R.outletUtilityTemperature = app.TwOutletEditField.Value ;
                        app.R.costUtility = app.CostUtilityEditField.Value ;
                    end
                end
                
                app.R.pressureMode = app.PressureDropMode.SelectedObject.Text ;
                
                if  strcmp(selectedButton.Text,'PFR')
                    app.R.pressureDropEqn = app.PressureDropEqn.SelectedObject.Text ;
                    if strcmp(app.PressureDropEqn.SelectedObject.Text,'Ergun')
                        app.R.particleDiameter = app.ParticlediametermEditField.Value ;
                    end
                end
                
                app.R.activatePlots  = app.DisplayplotsSwitch.Value ;
                
                assignin("base",app.NameEditField.Value,app.R) ;
            end
        end

        % Button pushed function: UtilitiesButton
        function UtilitiesButtonPushed(app, event)
            app.DialogApp = UtilitiesApp(app) ;
        end

        % Button pushed function: UnitconversionhelperButton
        function UnitconversionhelperButtonPushed(app, event)
            app.UnitsApp = unitConverterApp(app) ;
        end

        % Selection changed function: PressureDropEqn
        function PressureDropEqnSelectionChanged(app, event)
            selectedButton = app.PressureDropEqn.SelectedObject;
            if strcmp(selectedButton.Text,'Ergun')
                app.CatalystCheckBox.Value = logical(true) ;
                app.CatalystCheckBoxValueChanged(logical(true)) ;
                
                app.ParticlediametermEditField.Visible = 'on' ;
                app.ParticlediametermEditFieldLabel.Visible = 'on' ;
            else
                app.ParticlediametermEditField.Visible = 'off' ;
                app.ParticlediametermEditFieldLabel.Visible = 'off' ;
            end
        end

        % Value changed function: CatalystCheckBox
        function CatalystCheckBoxValueChanged(app, event)
            value = app.CatalystCheckBox.Value;
            if value
                app.DensityEditField.Enable = 'on' ;
                app.PorosityEditField.Enable = 'on' ;
            else
                app.DensityEditField.Enable = 'off' ;
                app.PorosityEditField.Enable = 'off' ;
            end
        end

        % Selection changed function: PressureDropMode
        function PressureDropModeSelectionChanged(app, event)
            if strcmp(app.TypeofreactorButtonGroup.SelectedObject.Text,'PFR') && strcmp(app.PressureDropMode.SelectedObject.Text,'Non constant')
                app.PressureDropEqn.Visible = 'on' ;
            else
                app.PressureDropEqn.Visible = 'off' ;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ReactorLabel = uilabel(app.UIFigure);
            app.ReactorLabel.Text = 'Reactor';
            app.ChooseanametoidentifythereactorLabel = uilabel(app.UIFigure);
            app.ChooseanametoidentifythereactorLabel.Text = 'Choose a name to identify the reactor';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.HeatexchangemodeButtonGroup = uibuttongroup(app.UIFigure);
            app.HeatexchangemodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HeatexchangemodeButtonGroupSelectionChanged, true);
            app.HeatexchangemodeButtonGroup.Title = 'Heat exchange mode';
            app.IsothermalButton = uiradiobutton(app.HeatexchangemodeButtonGroup);
            app.IsothermalButton.Text = 'Isothermal';
            app.AdiabaticButton = uiradiobutton(app.HeatexchangemodeButtonGroup);
            app.AdiabaticButton.Text = 'Adiabatic';
            app.OtherButton = uiradiobutton(app.HeatexchangemodeButtonGroup);
            app.OtherButton.Tooltip = {'Other implies the reactor is cooled or heated'};
            app.OtherButton.Text = 'Other';
            app.OtherButton.Value = true;
            app.PressureDropMode = uibuttongroup(app.UIFigure);
            app.PressureDropMode.SelectionChangedFcn = createCallbackFcn(app, @PressureDropModeSelectionChanged, true);
            app.PressureDropMode.Tooltip = {'Constant : momentum balnce is neglected'; ''; 'Not constant : momentum balance is considered'};
            app.PressureDropMode.Title = 'Does pressure change inside the reactor?';
            app.ConstantButton = uiradiobutton(app.PressureDropMode);
            app.ConstantButton.Text = 'Constant';
            app.ConstantButton.Value = true;
            app.NonconstantButton = uiradiobutton(app.PressureDropMode);
            app.NonconstantButton.Text = 'Non constant';
            app.VolumemEditFieldLabel = uilabel(app.UIFigure);
            app.VolumemEditFieldLabel.Tooltip = {'It is advisable to use SI units because all the operations use the gas constant as:'; 'R = 8,314 Pa·m^3/mol/K'};
            app.VolumemEditFieldLabel.Text = 'Volume (m³)';
            app.VolumemEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumemEditField.Tooltip = {'It is advisable to use SI units because all the operations use the gas constant as:'; 'R = 8,314 Pa·m^3/mol/K'};
            app.UnitsEditFieldLabel = uilabel(app.UIFigure);
            app.UnitsEditFieldLabel.Visible = 'off';
            app.UnitsEditFieldLabel.Tooltip = {'It is advisable to use SI units because all the operations use the gas constant as:'; 'R = 8,314 Pa·m^3/mol/K'};
            app.UnitsEditFieldLabel.Text = 'Units';
            app.UnitsEditField = uieditfield(app.UIFigure, 'text');
            app.UnitsEditField.Visible = 'off';
            app.UnitsEditField.Tooltip = {'It is advisable to use SI units because all the operations use the gas constant as:'; 'R = 8,314 Pa·m^3/mol/K'};
            app.BypassRatioEditFieldLabel = uilabel(app.UIFigure);
            app.BypassRatioEditFieldLabel.Text = 'Bypass Ratio';
            app.BypassRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.BypassRatioEditField.Limits = [0 Inf];
            app.BypassRatioEditField.Tooltip = {'Bypass Ratio = (moles bypassed) /(moles entering reactor)'};
            app.LmEditFieldLabel = uilabel(app.UIFigure);
            app.LmEditFieldLabel.Visible = 'off';
            app.LmEditFieldLabel.Tooltip = {'It is advisable to use SI units because all the operations use the gas constant as:'; 'R = 8,314 Pa·m^3/mol/K'};
            app.LmEditFieldLabel.Text = 'L (m)';
            app.LmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LmEditField.Visible = 'off';
            app.LmEditField.Tooltip = {'It is advisable to use SI units because all the operations use the gas constant as:'; 'R = 8,314 Pa·m^3/mol/K'};
            app.NumberoftubesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoftubesEditFieldLabel.Visible = 'off';
            app.NumberoftubesEditFieldLabel.Text = 'Number of tubes';
            app.NumberoftubesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoftubesEditField.Visible = 'off';
            app.NumberoftubesEditField.Value = 1;
            app.TubediametermEditFieldLabel = uilabel(app.UIFigure);
            app.TubediametermEditFieldLabel.Visible = 'off';
            app.TubediametermEditFieldLabel.Tooltip = {'It is advisable to use SI units because all the operations use the gas constant as:'; 'R = 8,314 Pa·m^3/mol/K'};
            app.TubediametermEditFieldLabel.Text = 'Tube diameter (m)';
            app.TubediametermEditField = uieditfield(app.UIFigure, 'numeric');
            app.TubediametermEditField.Visible = 'off';
            app.TubediametermEditField.Tooltip = {'It is advisable to use SI units because all the operations use the gas constant as:'; 'R = 8,314 Pa·m^3/mol/K'};
            app.TubediametermEditField.Value = 0.1;
            app.DensitykgmLabel = uilabel(app.UIFigure);
            app.DensitykgmLabel.Text = 'Density (kg/m³)';
            app.DensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.DensityEditField.Limits = [0 Inf];
            app.DensityEditField.Enable = 'off';
            app.PorosityEditFieldLabel = uilabel(app.UIFigure);
            app.PorosityEditFieldLabel.Text = 'Porosity';
            app.PorosityEditField = uieditfield(app.UIFigure, 'numeric');
            app.PorosityEditField.Limits = [0 1];
            app.PorosityEditField.Enable = 'off';
            app.UWmKLabel = uilabel(app.UIFigure);
            app.UWmKLabel.Text = 'U (W/m²/K)';
            app.UEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmLabel = uilabel(app.UIFigure);
            app.AmLabel.Text = 'A (m²)';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditField.Tooltip = {'If the reactor is a PFR, it is unnecessary to specify the heat transfer area (A) '};
            app.AEditField.Value = 1;
            app.TwinletKLabel = uilabel(app.UIFigure);
            app.TwinletKLabel.Text = 'Tw,inlet (K)';
            app.TwEditField = uieditfield(app.UIFigure, 'numeric');
            app.TwEditField.Value = 0.1;
            app.QUATTwLabel = uilabel(app.UIFigure);
            app.QUATTwLabel.Text = 'Q = UA ( T - Tw )';
            app.DisplayplotsSwitchLabel = uilabel(app.UIFigure);
            app.DisplayplotsSwitchLabel.Text = 'Display plots';
            app.DisplayplotsSwitch = uiswitch(app.UIFigure, 'slider');
            app.DisplayplotsSwitch.Items = {'off', 'on'};
            app.DisplayplotsSwitch.Value = 'off';
            app.CreateReactorButton = uibutton(app.UIFigure, 'push');
            app.CreateReactorButton.ButtonPushedFcn = createCallbackFcn(app, @CreateReactorButtonPushed, true);
            app.CreateReactorButton.Text = 'Create Reactor';
            app.ReactiontimesLabel = uilabel(app.UIFigure);
            app.ReactiontimesLabel.Visible = 'off';
            app.ReactiontimesLabel.Text = 'Reaction time (s)';
            app.ReactiontimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReactiontimesEditField.Visible = 'off';
            app.ReactiontimesEditField.Tooltip = {'It is advisable to use SI units because all the operations use the gas constant as:'; 'R = 8,314 Pa·m^3/mol/K'};
            app.UnitsEditField_2Label = uilabel(app.UIFigure);
            app.UnitsEditField_2Label.Visible = 'off';
            app.UnitsEditField_2Label.Text = 'Units';
            app.UnitsEditField_2 = uieditfield(app.UIFigure, 'text');
            app.UnitsEditField_2.Visible = 'off';
            app.TypeofreactorButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeofreactorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofreactorButtonGroupSelectionChanged, true);
            app.TypeofreactorButtonGroup.Title = 'Type of reactor';
            app.PFRButton = uiradiobutton(app.TypeofreactorButtonGroup);
            app.PFRButton.Text = 'PFR';
            app.CSTRButton = uiradiobutton(app.TypeofreactorButtonGroup);
            app.CSTRButton.Text = 'CSTR';
            app.BatchButton = uiradiobutton(app.TypeofreactorButtonGroup);
            app.BatchButton.Text = 'Batch';
            app.HelperButton = uiradiobutton(app.TypeofreactorButtonGroup);
            app.HelperButton.Visible = 'off';
            app.HelperButton.Text = 'Helper';
            app.HelperButton.Value = true;
            app.UtilitiesButton = uibutton(app.UIFigure, 'push');
            app.UtilitiesButton.ButtonPushedFcn = createCallbackFcn(app, @UtilitiesButtonPushed, true);
            app.UtilitiesButton.Icon = 'DBIcon.png';
            app.UtilitiesButton.Tooltip = {''};
            app.UtilitiesButton.Text = '';
            app.TwoutletKLabel = uilabel(app.UIFigure);
            app.TwoutletKLabel.Visible = 'off';
            app.TwoutletKLabel.Text = 'Tw,outlet (K)';
            app.TwOutletEditField = uieditfield(app.UIFigure, 'numeric');
            app.TwOutletEditField.Visible = 'off';
            app.TwOutletEditField.Value = Inf;
            app.UnitconversionhelperButton = uibutton(app.UIFigure, 'push');
            app.UnitconversionhelperButton.ButtonPushedFcn = createCallbackFcn(app, @UnitconversionhelperButtonPushed, true);
            app.UnitconversionhelperButton.Icon = 'UnitsLogo.png';
            app.UnitconversionhelperButton.Text = 'Unit conversion helper';
            app.PressureDropEqn = uibuttongroup(app.UIFigure);
            app.PressureDropEqn.SelectionChangedFcn = createCallbackFcn(app, @PressureDropEqnSelectionChanged, true);
            app.PressureDropEqn.Tooltip = {''};
            app.PressureDropEqn.Title = 'How to compute pressure drop?';
            app.PipeButton = uiradiobutton(app.PressureDropEqn);
            app.PipeButton.Tooltip = {'It implies that the pressure drop through the reactor is computed with a momentum balance considering it''s not a bed reactor'};
            app.PipeButton.Text = 'Pipe';
            app.PipeButton.Value = true;
            app.ErgunButton = uiradiobutton(app.PressureDropEqn);
            app.ErgunButton.Text = 'Ergun';
            app.CatalystCheckBox = uicheckbox(app.UIFigure);
            app.CatalystCheckBox.ValueChangedFcn = createCallbackFcn(app, @CatalystCheckBoxValueChanged, true);
            app.CatalystCheckBox.Text = 'Mark if the reactor is catalytic';
            app.UtilitiesDataBaseLabel = uilabel(app.UIFigure);
            app.UtilitiesDataBaseLabel.Text = 'Utilities Data Base';
            app.ParticlediametermEditFieldLabel = uilabel(app.UIFigure);
            app.ParticlediametermEditFieldLabel.Visible = 'off';
            app.ParticlediametermEditFieldLabel.Text = 'Particle diameter (m)';
            app.ParticlediametermEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParticlediametermEditField.Limits = [0 Inf];
            app.ParticlediametermEditField.Visible = 'off';
            app.CostkWyearEditFieldLabel = uilabel(app.UIFigure);
            app.CostkWyearEditFieldLabel.Visible = 'off';
            app.CostkWyearEditFieldLabel.Text = 'Cost ($/kW/year)';
            app.CostUtilityEditField = uieditfield(app.UIFigure, 'numeric');
            app.CostUtilityEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = defineReactorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end