classdef defineStreamApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StreamLabel                     matlab.ui.control.Label
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        UITableStreamData               matlab.ui.control.Table
        MolarFlowunitsEditFieldLabel    matlab.ui.control.Label
        MolarFlowunitsEditField         matlab.ui.control.EditField
        VolumetricFlowmsEditFieldLabel  matlab.ui.control.Label
        VolumetricFlowmsEditField       matlab.ui.control.NumericEditField
        UnitsEditField_3Label           matlab.ui.control.Label
        volumetricFlowUnitsEditField    matlab.ui.control.EditField
        PPaEditFieldLabel               matlab.ui.control.Label
        PressureEditField               matlab.ui.control.NumericEditField
        TKEditFieldLabel                matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        DensitykgmEditFieldLabel        matlab.ui.control.Label
        DensitykgmEditField             matlab.ui.control.NumericEditField
        ViscosityPasEditFieldLabel      matlab.ui.control.Label
        ViscosityPasEditField           matlab.ui.control.NumericEditField
        CreateStreamButton              matlab.ui.control.Button
        NumberofcomponentsSpinnerLabel  matlab.ui.control.Label
        NumberofcomponentsSpinner       matlab.ui.control.Spinner
        CheckBoxP                       matlab.ui.control.CheckBox
        CheckBoxT                       matlab.ui.control.CheckBox
        TextArea                        matlab.ui.control.TextArea
        PhaseDropDownLabel              matlab.ui.control.Label
        PhaseDropDown                   matlab.ui.control.DropDown
        UnitconversionhelperButton      matlab.ui.control.Button
        DefinestreamfromHysysButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        Y = Stream ; % Create a Stream class object
        UnitsApp
    end
    
    
    methods (Access = public)
        
        function displayStreamValues(app,materialStream)
            
            if isa(materialStream,'Stream')
                app.UITableStreamData.Data = array2table([materialStream.molarFlow' , materialStream.concentration']) ;
                rowNames = cell(1,length(materialStream.molarFlow)) ;
                for i=1:length(materialStream.molarFlow)
                    rowNames{i} = string(['Component ',num2str(i)]) ;
                end
                app.UITableStreamData.RowName = rowNames ;
                
                if ~isempty(materialStream.molarFlow_Units)
                    app.MolarFlowunitsEditField.Value = materialStream.molarFlow_Units ;
                end
                
                app.VolumetricFlowmsEditField.Value = materialStream.volumetricFlow ;
                if ~isempty(materialStream.volumetricFlow_Units)
                    app.volumetricFlowUnistEditField.Value = materialStream.volumetricFlow_Units ;
                end
                app.PressureEditField.Value = materialStream.P ;
                app.TemperatureEditField.Value = materialStream.T ;
                if ~isempty(materialStream.density)
                    app.DensitykgmEditField.Value = materialStream.density ;
                end
                if ~isempty(materialStream.viscosity)
                    app.ViscosityPasEditField.Value = materialStream.viscosity ;
                end
                if ~isempty(materialStream.phase)
                    app.PhaseDropDown.Value = materialStream.phase ;
                end
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.UITableStreamData.ColumnEditable = logical(true) ;
            
            if ~isempty(varargin)
                Product = varargin{1} ;
                
                app.NumberofcomponentsSpinner.Visible = 'off' ;
                app.CreateStreamButton.Visible = 'off' ;
                app.TextArea.Value = newline + "This window is showing the product stream which gives the solution to the given problem" ;
                app.TextArea.HorizontalAlignment = 'center' ;
                app.CheckBoxP.Visible = 'off' ;
                app.CheckBoxT.Visible = 'off' ;
                app.NameEditField.Value = 'Product' ;
                app.PhaseDropDown.Value = Product.phase ;
                
                app.displayStreamValues(Product);
                
                % Delete .mat file that stored variable ProductData
                delete("solutionVariablesFile.mat")
            end
            
        end

        % Value changed function: NumberofcomponentsSpinner
        function NumberofcomponentsSpinnerValueChanged(app, event)
            components = app.NumberofcomponentsSpinner.Value;
            
            % Sizing UITableStreamData
            %             app.UITableStreamData.Data = table('Size',[components,2],'VariableTypes',{'double','double'}) ;
            app.UITableStreamData.Data = cell(components,2) ;
            rowNames = cell(1,components) ;
            for i=1:components
                rowNames{i} = string(['Component ',num2str(i)]) ;
            end
            app.UITableStreamData.RowName = rowNames ;
            
        end

        % Button pushed function: CreateStreamButton
        function CreateStreamButtonPushed(app, event)
            if isempty(app.NameEditField.Value)
                msgbox('Name edit field is empty. Please, write an identifier before pushing "Create Stream" button', 'Warning','warn');
            else
                molarFlowCell = app.UITableStreamData.Data(:,1) ;
                concentrationCell = app.UITableStreamData.Data(:,2) ;
                
                app.Y.phase = app.PhaseDropDown.Value ;
                
                if ~isempty(cell2mat(molarFlowCell))
                    for i = 1:length(molarFlowCell)
                        if isempty(molarFlowCell{i})
                            molarFlowCell{i} = NaN ;
                        end
                        app.Y.molarFlow = (cell2mat(molarFlowCell))' ;
                    end
                end
                
                if ~isempty(cell2mat(concentrationCell))
                    for i = 1:length(concentrationCell)
                        if isempty(concentrationCell{i})
                            concentrationCell{i} = NaN ;
                        end
                        app.Y.concentration = (cell2mat(concentrationCell))' ;
                    end
                end
                
                if app.VolumetricFlowmsEditField.Value > 0
                    app.Y.volumetricFlow = app.VolumetricFlowmsEditField.Value ;
                end
                
                if app.PressureEditField.Value > 0
                    app.Y.P = app.PressureEditField.Value ;
                elseif app.CheckBoxP.Value
                    app.Y.P = [] ;
                end
                
                if app.TemperatureEditField.Value > 0
                    app.Y.T = app.TemperatureEditField.Value ;
                elseif app.CheckBoxT.Value
                    app.Y.T = [] ;
                end
                if app.DensitykgmEditField.Value > 0
                    app.Y.density = app.DensitykgmEditField.Value ;
                end
                if app.ViscosityPasEditField.Value > 0
                    app.Y.viscosity = app.ViscosityPasEditField.Value ;
                end
                
                assignin("base",app.NameEditField.Value,app.Y)
                
                % Display authomatically calculated values that were not specified
                if sum(isempty(app.Y.molarFlow)) == 0
                    for i = 1 : length(app.Y.molarFlow)
                        if ~isnan(app.Y.molarFlow(i))
                            app.UITableStreamData.Data{i,1} = app.Y.molarFlow(i) ;
                        end
                    end
                end
                
                if sum(isempty(app.Y.concentration)) == 0
                    for i = 1 : length(app.Y.concentration)
                        if ~isnan(app.Y.concentration(i))
                            app.UITableStreamData.Data{i,2} = app.Y.concentration(i) ;
                        end
                    end
                end
                
                if app.VolumetricFlowmsEditField.Value == 0
                    if ~isnan(app.Y.volumetricFlow)
                        app.VolumetricFlowmsEditField.Value = app.Y.volumetricFlow ;
                    end
                end
            end
        end

        % Button pushed function: UnitconversionhelperButton
        function UnitconversionhelperButtonPushed(app, event)
            app.UnitsApp = unitConverterApp(app) ;
        end

        % Value changed function: CheckBoxP
        function CheckBoxPValueChanged(app, event)
            if app.CheckBoxP.Value
                app.PressureEditField.BackgroundColor = [0.8 0.8 0.8] ;
                app.PressureEditField.Editable = 'off' ;
            else
                app.PressureEditField.BackgroundColor = [1,1,1] ;
                app.PressureEditField.Editable = 'on' ;
            end
        end

        % Value changed function: CheckBoxT
        function CheckBoxTValueChanged(app, event)
            if app.CheckBoxT.Value
                app.TemperatureEditField.BackgroundColor = [0.8 0.8 0.8] ;
                app.TemperatureEditField.Editable = 'off' ;
            else
                app.TemperatureEditField.BackgroundColor = [1,1,1] ;
                app.TemperatureEditField.Editable = 'on' ;
            end
        end

        % Cell edit callback: UITableStreamData
        function UITableStreamDataCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isa(newData,'char')
                app.UITableStreamData.Data{indices(1),indices(2)} = str2double(newData) ;
            elseif isnan(newData)
                app.UITableStreamData.Data{indices(1),indices(2)} = [] ;
            end
        end

        % Button pushed function: DefinestreamfromHysysButton
        function DefinestreamfromHysysButtonPushed(app, event)
            dialogHysysStreamApp(app) ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StreamLabel = uilabel(app.UIFigure);
            app.StreamLabel.Text = 'Stream';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Tooltip = {'Choose a name to identify the stream'};
            app.UITableStreamData = uitable(app.UIFigure);
            app.UITableStreamData.ColumnName = {'Molar Flow'; 'Concentration'};
            app.UITableStreamData.RowName = {};
            app.UITableStreamData.ColumnEditable = true;
            app.UITableStreamData.CellEditCallback = createCallbackFcn(app, @UITableStreamDataCellEdit, true);
            app.UITableStreamData.Tooltip = {''};
            app.MolarFlowunitsEditFieldLabel = uilabel(app.UIFigure);
            app.MolarFlowunitsEditFieldLabel.Visible = 'off';
            app.MolarFlowunitsEditFieldLabel.Text = 'Molar Flow units';
            app.MolarFlowunitsEditField = uieditfield(app.UIFigure, 'text');
            app.MolarFlowunitsEditField.Visible = 'off';
            app.VolumetricFlowmsEditFieldLabel = uilabel(app.UIFigure);
            app.VolumetricFlowmsEditFieldLabel.Text = 'Volumetric Flow (m³/s)';
            app.VolumetricFlowmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumetricFlowmsEditField.Tooltip = {'It is advisable to use SI units because all the operations use the gas constant as:'; 'R = 8,314 Pa·m^3/mol/K'};
            app.UnitsEditField_3Label = uilabel(app.UIFigure);
            app.UnitsEditField_3Label.Visible = 'off';
            app.UnitsEditField_3Label.Text = 'Units';
            app.volumetricFlowUnitsEditField = uieditfield(app.UIFigure, 'text');
            app.volumetricFlowUnitsEditField.Visible = 'off';
            app.PPaEditFieldLabel = uilabel(app.UIFigure);
            app.PPaEditFieldLabel.Text = 'P (Pa)';
            app.PressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TKEditFieldLabel = uilabel(app.UIFigure);
            app.TKEditFieldLabel.Text = 'T (K)';
            app.TemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.DensitykgmEditFieldLabel = uilabel(app.UIFigure);
            app.DensitykgmEditFieldLabel.Text = 'Density (kg/m³)';
            app.DensitykgmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ViscosityPasEditFieldLabel = uilabel(app.UIFigure);
            app.ViscosityPasEditFieldLabel.Text = 'Viscosity (Pa·s)';
            app.ViscosityPasEditField = uieditfield(app.UIFigure, 'numeric');
            app.CreateStreamButton = uibutton(app.UIFigure, 'push');
            app.CreateStreamButton.ButtonPushedFcn = createCallbackFcn(app, @CreateStreamButtonPushed, true);
            app.CreateStreamButton.Text = 'Create Stream';
            app.NumberofcomponentsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofcomponentsSpinnerLabel.Text = 'Number of components';
            app.NumberofcomponentsSpinner = uispinner(app.UIFigure);
            app.NumberofcomponentsSpinner.Limits = [0 Inf];
            app.NumberofcomponentsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofcomponentsSpinnerValueChanged, true);
            app.CheckBoxP = uicheckbox(app.UIFigure);
            app.CheckBoxP.ValueChangedFcn = createCallbackFcn(app, @CheckBoxPValueChanged, true);
            app.CheckBoxP.Text = '';
            app.CheckBoxT = uicheckbox(app.UIFigure);
            app.CheckBoxT.ValueChangedFcn = createCallbackFcn(app, @CheckBoxTValueChanged, true);
            app.CheckBoxT.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'TIPS'; 'If a PRODUCT stream is being defined and you do not want to specify :'; '  - P or T >> select the check box.'; '  - The composition of any species >> simply do not enter any data in the corresponding cell'};
            app.PhaseDropDownLabel = uilabel(app.UIFigure);
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.UIFigure);
            app.PhaseDropDown.Items = {'L', 'G'};
            app.PhaseDropDown.Tooltip = {'Select ''L'' if liquid phase or ''G'' if gas phase'};
            app.PhaseDropDown.Value = 'L';
            app.UnitconversionhelperButton = uibutton(app.UIFigure, 'push');
            app.UnitconversionhelperButton.ButtonPushedFcn = createCallbackFcn(app, @UnitconversionhelperButtonPushed, true);
            app.UnitconversionhelperButton.Icon = 'UnitsLogo.png';
            app.UnitconversionhelperButton.Text = 'Unit conversion helper';
            app.DefinestreamfromHysysButton = uibutton(app.UIFigure, 'push');
            app.DefinestreamfromHysysButton.ButtonPushedFcn = createCallbackFcn(app, @DefinestreamfromHysysButtonPushed, true);
            app.DefinestreamfromHysysButton.Icon = 'HysysLogo.png';
            app.DefinestreamfromHysysButton.Text = 'Define stream from Hysys';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = defineStreamApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end