classdef dialogHysysStreamApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileLocationEditFieldLabel  matlab.ui.control.Label
        FileLocationEditField       matlab.ui.control.EditField
        FileNameEditFieldLabel      matlab.ui.control.Label
        FileNameEditField           matlab.ui.control.EditField
        StreamNameEditFieldLabel    matlab.ui.control.Label
        StreamNameEditField         matlab.ui.control.EditField
        OKButton                    matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        xmlLabel                    matlab.ui.control.Label
        DefinestreamfromAspenHysysLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp ;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            fileLocation    = app.FileLocationEditField.Value ;
            fileName        = app.FileNameEditField.Value ;
            streamName      = app.StreamNameEditField.Value ;
            
            if isempty(fileLocation)
                fileLocation = [] ;
            end
            streamCopy          = Stream ;
            streamCopy          = streamCopy.defineStreamFromHysys(fileLocation, fileName, streamName) ;
            app.CallingApp.Y    = streamCopy ;
            
            displayStreamValues(app.CallingApp,streamCopy) ;
            app.CallingApp.volumetricFlowUnitsEditField.Value = 'm^3/s' ;
            app.CallingApp.MolarFlowunitsEditField.Value = 'moles/s' ;
            
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileLocationEditFieldLabel = uilabel(app.UIFigure);
            app.FileLocationEditFieldLabel.Text = 'File Location';
            app.FileLocationEditField = uieditfield(app.UIFigure, 'text');
            app.FileLocationEditField.Tooltip = {'Optional.'; ''; 'If this gap is not filled, the .xml file is searched in the current working folder'};
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Tooltip = {'Compulsory'};
            app.StreamNameEditFieldLabel = uilabel(app.UIFigure);
            app.StreamNameEditFieldLabel.Text = 'Stream Name';
            app.StreamNameEditField = uieditfield(app.UIFigure, 'text');
            app.StreamNameEditField.Tooltip = {'Compulsory'};
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'HysysLogo.png';
            app.xmlLabel = uilabel(app.UIFigure);
            app.xmlLabel.Text = '.xml';
            app.DefinestreamfromAspenHysysLabel = uilabel(app.UIFigure);
            app.DefinestreamfromAspenHysysLabel.Text = 'Define stream from Aspen Hysys';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialogHysysStreamApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end