classdef UtilitiesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        HotUtilitiesTab     matlab.ui.control.Table
        ColdUtilitiesTab    matlab.ui.control.Table
        HotUtilitiesLabel   matlab.ui.control.Label
        ColdUtilitiesLabel  matlab.ui.control.Label
        OKButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, reactorApp)
            load('UtilitiesDataBase.mat')
            nRowsHot = size(HotUtilitiesTable,1) ;
            newColumnHot = table('Size',[nRowsHot,1],'VariableTypes',"logical") ;
            app.HotUtilitiesTab.Data = [HotUtilitiesTable newColumnHot] ;
            app.HotUtilitiesTab.ColumnName = {'Tin(ºC)','Tout(ºC)','U (W/m²/K)','Cost ($/kW/year)','Select'} ;
            app.HotUtilitiesTab.RowName = HotUtilitiesTable.Row ;
            app.HotUtilitiesTab.ColumnEditable = logical([1,1,1,1,1]) ;
            
            nRowsCold = size(ColdUtilitiesTable,1) ;
            newColumnCold = table('Size',[nRowsCold,1],'VariableTypes',"logical") ;
            app.ColdUtilitiesTab.Data = [ColdUtilitiesTable newColumnCold] ;
            app.ColdUtilitiesTab.ColumnName = {'Tin(ºC)','Tout(ºC)','U (W/m²/K)','Cost ($/kW/year)','Select'} ;
            app.ColdUtilitiesTab.RowName = ColdUtilitiesTable.Row ;
            app.ColdUtilitiesTab.ColumnEditable = logical([1,1,1,1,1]) ;
            
            app.CallingApp = reactorApp ;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            selectedHot = app.HotUtilitiesTab.Data{:,5} ;
            selectedCold = app.ColdUtilitiesTab.Data{:,5} ;
            if sum(selectedHot)+sum(selectedCold) > 1
                error('Only one utility can be selected')
            elseif sum(selectedHot)+sum(selectedCold) == 1
                if sum(selectedHot) == 1
                    selection = app.HotUtilitiesTab.Data{selectedHot,:} ;
                elseif sum(selectedCold) == 1
                    selection = app.ColdUtilitiesTab.Data{selectedCold,:} ;
                end
            end
            app.CallingApp.UEditField.Value = selection(3) ;
            app.CallingApp.TwEditField.Value = selection(1);
            
            app.CallingApp.TwoutletKLabel.Visible = 'on' ; 
            app.CallingApp.TwOutletEditField.Visible = 'on' ;
            app.CallingApp.CostkWyearEditFieldLabel.Visible = 'on' ; 
            app.CallingApp.CostUtilityEditField.Visible = 'on' ;
            
            app.CallingApp.TwOutletEditField.Value= selection(2) ;
            app.CallingApp.CostUtilityEditField.Value = selection(4) ;
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HotUtilitiesTab = uitable(app.UIFigure);
            app.HotUtilitiesTab.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.HotUtilitiesTab.RowName = {};
            app.ColdUtilitiesTab = uitable(app.UIFigure);
            app.ColdUtilitiesTab.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ColdUtilitiesTab.RowName = {};
            app.HotUtilitiesLabel = uilabel(app.UIFigure);
            app.HotUtilitiesLabel.Text = 'Hot Utilities';
            app.ColdUtilitiesLabel = uilabel(app.UIFigure);
            app.ColdUtilitiesLabel.Text = 'Cold Utilities';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UtilitiesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end