classdef defineReactionSysApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReactionSysDefinitionUIFigure   matlab.ui.Figure
        ReactiveSystemLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NumberofcomponentsSpinnerLabel  matlab.ui.control.Label
        NumberofcomponentsSpinner       matlab.ui.control.Spinner
        UITable                         matlab.ui.control.Table
        NumberofreactionsSpinnerLabel   matlab.ui.control.Label
        NumberofreactionsSpinner        matlab.ui.control.Spinner
        StochiometricMatrixLabel        matlab.ui.control.Label
        reactionsxcomponentsLabel       matlab.ui.control.Label
        CreateReactiveSystemButton      matlab.ui.control.StateButton
        ChooseanametostorethedataintheworkspaceLabel  matlab.ui.control.Label
        KineticsTab                     matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        ArrheniusRateLawconstantsLabel  matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        PushtoaddanewterminthesumofthedenominatorLabel  matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        NumeratoroftherateexpressionLabel  matlab.ui.control.Label
        ConstantstodefinethedenominatorLabel  matlab.ui.control.Label
        UITable6                        matlab.ui.control.Table
        KineticsButtonGroup             matlab.ui.container.ButtonGroup
        LangmuirHinshelwoodRateLawButton  matlab.ui.control.RadioButton
        OtherkineticsButton             matlab.ui.control.RadioButton
        TextArea                        matlab.ui.control.TextArea
        SpecifythenameofthefunctionEditFieldLabel  matlab.ui.control.Label
        SpecifythenameofthefunctionEditField  matlab.ui.control.EditField
        OtherKineticsFormat             matlab.ui.container.ButtonGroup
        FromfileButton                  matlab.ui.control.RadioButton
        UsingtablebelowButton           matlab.ui.control.RadioButton
        ThermodynamicsTab               matlab.ui.container.Tab
        PropertySelelctionDropDownLabel  matlab.ui.control.Label
        PropertySelelctionDropDown      matlab.ui.control.DropDown
        UITable4                        matlab.ui.control.Table
        UITable5                        matlab.ui.control.Table
        TrefKEditFieldLabel             matlab.ui.control.Label
        TrefKEditField                  matlab.ui.control.NumericEditField
        CpoptionDropDownLabel           matlab.ui.control.Label
        CpoptionDropDown                matlab.ui.control.DropDown
        WorkingdirectoryEditFieldLabel  matlab.ui.control.Label
        Workingdirectory                matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        Filename                        matlab.ui.control.EditField
        StreamnameEditFieldLabel        matlab.ui.control.Label
        Streamname                      matlab.ui.control.EditField
        HysysFileDropDownLabel          matlab.ui.control.Label
        HysysFileDropDown               matlab.ui.control.DropDown
        LoaddatafromHysysButton         matlab.ui.control.Button
        UnitconversionhelperButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        nDenominatorTerms =  0 ; % Number of terms in the denominator of the rate Law
        fileLocation % Information about where the Hysys file to be used is located
        
        UnitsApp
    end
    
    properties (Access = public)
        RS = ReactionSys ; % ReactionSys Object % Description
    end
    
    methods (Access = private)
        
        function visibilityHysysItems(app,state)
            app.CpoptionDropDown.Visible = state ;
            app.CpoptionDropDownLabel.Visible = state ;
            app.Workingdirectory.Visible = state ;
            app.WorkingdirectoryEditFieldLabel.Visible = state ;
            app.Filename.Visible = state ;
            app.FilenameEditFieldLabel.Visible = state ;
            app.Streamname.Visible = state ;
            app.StreamnameEditFieldLabel.Visible = state ;
            app.HysysFileDropDown.Visible = state ;
            app.HysysFileDropDownLabel.Visible = state ;
            app.LoaddatafromHysysButton.Visible = state ;
        end
        
        function visibilityUserDefinedThermodinamics(app,state)
            app.UITable4.Visible = state ;
            app.UITable5.Visible = state ;
            app.TrefKEditField.Visible = state ;
            app.TrefKEditFieldLabel.Visible = state ;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumberofcomponentsSpinner, 
        % NumberofreactionsSpinner
        function NumberofcomponentsSpinnerValueChanged(app, event)
           if app.NumberofcomponentsSpinner.Value > 0 && app.NumberofreactionsSpinner.Value > 0
            
            % Sets the number of columns and rows of the stochiometric matrix
            components = app.NumberofcomponentsSpinner.Value;
            reactions = app.NumberofreactionsSpinner.Value ;
            
            % Sizing UITable
            app.UITable.Data = cell(reactions,components) ;
            columnNames = cell(1,components) ;
            unicodeLetterID = 65 ; % Corresponds to letter A
            for i = 1:components
                columnNames{i} = char(unicodeLetterID) ;
                unicodeLetterID = unicodeLetterID + 1 ;
            end
            rowNames = cell(1,reactions) ;
            for i=1:reactions
                rowNames{i} = string(['Reaction ',num2str(i)]) ;
            end
            app.UITable.ColumnName = columnNames ;
            app.UITable.ColumnEditable = logical(true) ;
            app.UITable.RowName = rowNames ;
            
            % Sizing UITable2
            rowNames2 = rowNames ;
            app.UITable2.Data = cell(reactions,2) ;
            app.UITable2.RowName = rowNames2 ;
            app.UITable2.ColumnEditable = [logical(true) logical(true)] ;
            
            % Sizing UITable4
            rowNames4 = columnNames ;
            app.UITable4.Data = cell(components,2) ;
            app.UITable4.RowName = rowNames4 ;
            app.UITable4.ColumnEditable = logical(true) ;
            
            % Sizing UITable5
            rowNames5 = rowNames2 ;
            app.UITable5.Data = cell(reactions,1) ;
            app.UITable5.RowName = rowNames5 ;
            app.UITable5.ColumnEditable = logical(true) ;
            
            % Sizing UITable 6
            rowNames6 = rowNames2 ;
            userDefKineticsTable = array2table(strings(reactions,1)) ;
            app.UITable6.Data = userDefKineticsTable ;
            app.UITable6.RowName = rowNames6 ;
            app.UITable6.ColumnEditable = logical(true) ;
            
           end
           
            % Hiding UITables 3,4 and 5
            app.UITable3.Visible = 'off' ;
            app.UITable4.Visible = 'off' ;
            app.UITable5.Visible = 'off' ;
            app.TrefKEditField.Visible = 'off' ;
            app.TrefKEditFieldLabel.Visible = 'off' ;
            app.CpoptionDropDown.Visible = 'off' ;
            app.CpoptionDropDownLabel.Visible = 'off' ;
        end

        % Value changed function: CreateReactiveSystemButton
        function CreateReactiveSystemButtonValueChanged(app, event)
            if isempty(app.NameEditField.Value)
                msgbox('Name edit field is empty. Please, write an identifier before pushing "Create Reactive System" button', 'Warning','warn');
            else
                app.RS.stochiometricMatrix = cell2mat(app.UITable.Data) ;
                
                switch app.KineticsButtonGroup.SelectedObject.Text
                    case 'Langmuir-Hinshelwood Rate Law'
                        k0DataArray = cell2mat(app.UITable2.Data(:,1)) ;
                        if ~isempty(k0DataArray)
                            app.RS.k0 = k0DataArray' ;
                        end
                        EaDataArray = cell2mat(app.UITable2.Data(:,2)) ;
                        if ~isempty(EaDataArray)
                            app.RS.Ea = EaDataArray' ;
                        end
                        if app.nDenominatorTerms > 0
                            langmuirDataArray = cell2mat(app.UITable3.Data) ;
                            app.RS.k0_denominator = langmuirDataArray(:,1)' ;
                            app.RS.Ea_denominator = langmuirDataArray(:,2)' ;
                            app.RS.partialOrders_denominator = langmuirDataArray(:,3:end)' ;
                        end
                    case 'Other kinetics'
                        switch app.OtherKineticsFormat.SelectedObject.Text
                            case 'From file'
                                command = "@(concentration,T) " + app.SpecifythenameofthefunctionEditField.Value + "(concentration,T) ;" ;
                            case 'Using table below'
                                rateEquationsArray = cell2mat(app.UITable6.Data) ;
                                command = "@(concentration,T) [" ;
                                for i=1:length(rateEquationsArray)-1
                                    command = command + rateEquationsArray(i) + ',' ;
                                end
                                command = command + rateEquationsArray(end) + '] ;' ;
                        end
                        
                        app.RS.userDefinedKinetics = eval(command) ;
                end
                
                if strcmp(app.PropertySelelctionDropDown.Value,'User defined')
                    array4 = cell2mat(app.UITable4.Data) ;
                    
                    Cp = cell2mat(app.UITable4.Data(:,1)) ;
                    Mw = cell2mat(app.UITable4.Data(:,2)) ;
                    
                    [DHref] = cell2mat(app.UITable5.Data) ;
                    if ~isempty(Cp)
                        app.RS.componentCp = Cp' ;
                    end
                    if ~isempty(Mw)
                        app.RS.componentMw = Mw' ;
                    end
                    if ~isempty(DHref)
                        app.RS.Tref = app.TrefKEditField.Value ;
                        app.RS.DHref = DHref' ;
                    end
                elseif strcmp(app.PropertySelelctionDropDown.Value,'Import from Hysys')
                    if strcmp(app.CpoptionDropDown.Value,'Average')
                        array4 = cell2mat(app.UITable4.Data) ;
                        Cp = array4(:,1) ;
                        if Cp > 0
                            app.RS.componentCp = Cp' ;
                        end
                    end
                end
                
                assignin("base",app.NameEditField.Value,app.RS)
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.UITable3.Visible = 'on' ;
            
            % Sizing table 3
            nColumns = app.NumberofcomponentsSpinner.Value + 2; % k0 Ea partial order of each component
            columnNames = cell(1,nColumns) ;
            for i=1:nColumns
                if i == 1
                    columnNames{i}='k0_denominator' ;
                elseif i == 2
                    columnNames{i} = 'Ea_denominator' ;
                else
                    columnNames{i} = string(['P.O.Comp ',num2str(i-2)]) ;
                end
            end
            app.UITable3.ColumnName = columnNames ;
            app.UITable3.ColumnEditable = logical(true) ;
            
            if app.nDenominatorTerms < 0
                
                app.UITable3.Data = cell(1,nColumns) ;
                app.UITable3.Visible = 'on' ;
                
            else
                newRow = cell(1,nColumns) ;
                app.UITable3.Data = [app.UITable3.Data ; newRow] ;
            end
            
            app.nDenominatorTerms = app.nDenominatorTerms + 1 ;
        end

        % Button pushed function: LoaddatafromHysysButton
        function LoaddatafromHysysButtonPushed(app, event)
            if strcmp(app.HysysFileDropDown.Value,'Another file')
                app.fileLocation = {app.Workingdirectory.Value,app.Filename.Value,app.Streamname.Value} ;
                app.RS = app.RS.setHysysProperties(app.Workingdirectory.Value,app.Filename.Value,app.Streamname.Value) ;
            elseif strcmp(app.HysysFileDropDown.Value, 'Default (ComponentDataBase.xml)')
                app.RS = app.RS.setHysysProperties ;
            end
            
            if strcmp(app.CpoptionDropDown.Value,'Average')
                app.RS.componentCp.option = 'Average' ;
                CpArray = app.RS.componentCp.Average ;
            elseif strcmp(app.CpoptionDropDown.Value,'Cp = f(T)')
                app.RS.componentCp.option = 'Cp = f(T)' ;
                CpArray = strings(size(app.RS.componentCp.Function)) ;
                for i=1:size(app.RS.componentCp.Function,2)
                    CpArray(i) = "Function" ;
                    app.UITable4.ColumnEditable = logical([false true]) ;
                end
            elseif strcmp(app.CpoptionDropDown.Value,'Cp = f(T,P)')
                app.RS.componentCp.option = 'Cp = f(T,P)' ;
                CpArray = strings(size(app.RS.componentCp.FunctionWithP)) ;
                for i=1:size(app.RS.componentCp.FunctionWithP,2)
                    CpArray(i) = "Function" ;
                    app.UITable4.ColumnEditable = logical([false true]) ;
                end
            end
            
            MwArray = app.RS.componentMw ;
            DHformArray = app.RS.componentHeatOfFormation ;
            
            app.UITable4.Data = array2table([CpArray' MwArray']) ;
            app.UITable5.Data = array2table(DHformArray') ;
            app.UITable4.RowName = app.RS.componentNames ;
            app.UITable5.RowName = app.RS.componentNames ;
            app.UITable5.ColumnName = string([char(916),"Hformation (J/mol)"]) ;
            app.UITable4.Visible = 'on' ;
            app.UITable5.Visible = 'on' ;
            
        end

        % Value changed function: PropertySelelctionDropDown
        function PropertySelelctionDropDownValueChanged(app, event)
            switch app.PropertySelelctionDropDown.Value
                case ''
                    app.visibilityHysysItems('off') ;
                    app.visibilityUserDefinedThermodinamics('off') ;
                case 'Import from Hysys'
                    app.visibilityHysysItems('on') ;
                    app.visibilityUserDefinedThermodinamics('off') ;
                case 'User defined'
                    app.visibilityHysysItems('off') ;
                    app.visibilityUserDefinedThermodinamics('on') ;
            end
        end

        % Value changed function: CpoptionDropDown, Filename, 
        % HysysFileDropDown, Streamname, Workingdirectory
        function HysysFileDropDownValueChanged(app, event)
            switch app.HysysFileDropDown.Value
                case 'Default (ComponentDataBase.xml)'
                    app.Workingdirectory.Value = '' ;
                    app.Filename.Value = '' ;
                    app.Streamname.Value = '' ;
                    app.fileLocation = {} ;
                    
                    app.Workingdirectory.Editable = 'off' ;
                    app.Filename.Editable = 'off' ;
                    app.Streamname.Editable = 'off' ;
                case 'Another file'
                    app.Workingdirectory.Editable = 'on' ;
                    app.Filename.Editable = 'on' ;
                    app.Streamname.Editable = 'on' ;
            end
            
            check1 = isempty(app.CpoptionDropDown.Value) ;
            check2 = isempty(app.HysysFileDropDown.Value) ;
            if strcmp(app.HysysFileDropDown.Value,'Another file')
                check3 = isempty(app.Workingdirectory.Value) + isempty(app.Filename.Value) +isempty(app.Streamname.Value) ;
            else
                check3 = 0 ;
            end
            if check1 + check2 + check3 == 0
                app.LoaddatafromHysysButton.Enable = 'on' ;
            end
        end

        % Button pushed function: UnitconversionhelperButton
        function UnitconversionhelperButtonPushed(app, event)
            app.UnitsApp = unitConverterApp(app) ;
        end

        % Selection changed function: KineticsButtonGroup
        function KineticsButtonGroupSelectionChanged(app, event)
            selectedButton = app.KineticsButtonGroup.SelectedObject.Text;
            switch selectedButton
                case 'Langmuir-Hinshelwood Rate Law'
                    app.UITable2.Visible = 'on' ;
                    app.UITable3.Visible = 'on' ;
                    app.ArrheniusRateLawconstantsLabel.Visible = 'on' ;
                    app.NumeratoroftherateexpressionLabel.Visible = 'on' ;
                    app.Image.Visible = 'on' ;
                    app.ConstantstodefinethedenominatorLabel.Visible = 'on' ;
                    app.AddButton.Visible = 'on' ;
                    app.UITable6.Visible = 'off' ;
                    app.TextArea.Visible = 'off' ;
                    app.OtherKineticsFormat.Visible = 'off' ;
                    app.SpecifythenameofthefunctionEditField.Visible = 'off' ;
                    app.SpecifythenameofthefunctionEditFieldLabel.Visible = 'off' ;
                case 'Other kinetics'
                    app.UITable2.Visible = 'off' ;
                    app.UITable3.Visible = 'on' ;
                    app.ArrheniusRateLawconstantsLabel.Visible = 'off' ;
                    app.NumeratoroftherateexpressionLabel.Visible = 'off' ;
                    app.Image.Visible = 'off' ;
                    app.ConstantstodefinethedenominatorLabel.Visible = 'off' ;
                    app.AddButton.Visible = 'off' ;
                    app.UITable6.Visible = 'on' ;
                    app.TextArea.Visible = 'on' ;
                    app.OtherKineticsFormat.Visible = 'on' ;
                    app.SpecifythenameofthefunctionEditField.Visible = 'on' ;
                    app.SpecifythenameofthefunctionEditFieldLabel.Visible = 'on' ;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if isa(newData,'char')
                app.UITable.Data{indices(1),indices(2)} = str2double(newData) ;
            end
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isa(newData,'char')
                app.UITable2.Data{indices(1),indices(2)} = str2double(newData) ;
            end
        end

        % Cell edit callback: UITable3
        function UITable3CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isa(newData,'char')
                app.UITable3.Data{indices(1),indices(2)} = str2double(newData) ;
            end
        end

        % Cell edit callback: UITable4
        function UITable4CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isa(newData,'char')
                app.UITable4.Data{indices(1),indices(2)} = str2double(newData) ;
            end
        end

        % Cell edit callback: UITable5
        function UITable5CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isa(newData,'char')
                app.UITable5.Data{indices(1),indices(2)} = str2double(newData) ;
            end
        end

        % Cell edit callback: UITable6
        function UITable6CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isa(newData,'char')
                app.UITable6.Data{indices(1),indices(2)} = str2double(newData) ;
            end
        end

        % Selection changed function: OtherKineticsFormat
        function OtherKineticsFormatSelectionChanged(app, event)
            selectedButton = app.OtherKineticsFormat.SelectedObject;
            switch selectedButton.Text
                case 'From file'
                    app.SpecifythenameofthefunctionEditField.Enable = 'on' ;
                    app.UITable6.Enable = 'off' ;
                case 'Using table below'
                    app.SpecifythenameofthefunctionEditField.Enable = 'off' ;
                    app.UITable6.Enable = 'on' ;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReactionSysDefinitionUIFigure = uifigure('Visible', 'off');
            app.ReactionSysDefinitionUIFigure.Name = 'ReactionSysDefinition';
            app.ReactiveSystemLabel = uilabel(app.ReactionSysDefinitionUIFigure);
            app.ReactiveSystemLabel.Text = 'Reactive System';
            app.TabGroup = uitabgroup(app.ReactionSysDefinitionUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.NameEditFieldLabel = uilabel(app.GeneralTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GeneralTab, 'text');
            app.NumberofcomponentsSpinnerLabel = uilabel(app.GeneralTab);
            app.NumberofcomponentsSpinnerLabel.Text = 'Number of components';
            app.NumberofcomponentsSpinner = uispinner(app.GeneralTab);
            app.NumberofcomponentsSpinner.Limits = [0 Inf];
            app.NumberofcomponentsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofcomponentsSpinnerValueChanged, true);
            app.UITable = uitable(app.GeneralTab);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.NumberofreactionsSpinnerLabel = uilabel(app.GeneralTab);
            app.NumberofreactionsSpinnerLabel.Text = 'Number of reactions';
            app.NumberofreactionsSpinner = uispinner(app.GeneralTab);
            app.NumberofreactionsSpinner.Limits = [0 Inf];
            app.NumberofreactionsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofcomponentsSpinnerValueChanged, true);
            app.StochiometricMatrixLabel = uilabel(app.GeneralTab);
            app.StochiometricMatrixLabel.Text = 'Stochiometric Matrix ';
            app.reactionsxcomponentsLabel = uilabel(app.GeneralTab);
            app.reactionsxcomponentsLabel.Text = '[reactions x components]';
            app.CreateReactiveSystemButton = uibutton(app.GeneralTab, 'state');
            app.CreateReactiveSystemButton.ValueChangedFcn = createCallbackFcn(app, @CreateReactiveSystemButtonValueChanged, true);
            app.CreateReactiveSystemButton.Text = 'Create Reactive System';
            app.ChooseanametostorethedataintheworkspaceLabel = uilabel(app.GeneralTab);
            app.ChooseanametostorethedataintheworkspaceLabel.Text = 'Choose a name to store the data in the workspace';
            app.KineticsTab = uitab(app.TabGroup);
            app.KineticsTab.Title = 'Kinetics';
            app.UITable2 = uitable(app.KineticsTab);
            app.UITable2.ColumnName = {'k0'; 'Ea'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.ArrheniusRateLawconstantsLabel = uilabel(app.KineticsTab);
            app.ArrheniusRateLawconstantsLabel.Text = 'Arrhenius Rate Law constants';
            app.AddButton = uibutton(app.KineticsTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.PushtoaddanewterminthesumofthedenominatorLabel = uilabel(app.KineticsTab);
            app.PushtoaddanewterminthesumofthedenominatorLabel.Text = 'Push to add a new term in the sum of the denominator';
            app.UITable3 = uitable(app.KineticsTab);
            app.UITable3.ColumnName = {''};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = true;
            app.UITable3.CellEditCallback = createCallbackFcn(app, @UITable3CellEdit, true);
            app.Image = uiimage(app.KineticsTab);
            app.Image.Tooltip = {'It is not necessary to specify constants of the denominator. In this case, '; 'Den = 1'};
            app.Image.ImageSource = 'RateHelp.png';
            app.NumeratoroftherateexpressionLabel = uilabel(app.KineticsTab);
            app.NumeratoroftherateexpressionLabel.Text = 'Numerator of the rate expression';
            app.ConstantstodefinethedenominatorLabel = uilabel(app.KineticsTab);
            app.ConstantstodefinethedenominatorLabel.Text = 'Constants to define the denominator';
            app.UITable6 = uitable(app.KineticsTab);
            app.UITable6.ColumnName = {'Insert the rate equation for each reaction'};
            app.UITable6.RowName = {};
            app.UITable6.CellEditCallback = createCallbackFcn(app, @UITable6CellEdit, true);
            app.UITable6.Visible = 'off';
            app.KineticsButtonGroup = uibuttongroup(app.KineticsTab);
            app.KineticsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @KineticsButtonGroupSelectionChanged, true);
            app.LangmuirHinshelwoodRateLawButton = uiradiobutton(app.KineticsButtonGroup);
            app.LangmuirHinshelwoodRateLawButton.Text = 'Langmuir-Hinshelwood Rate Law';
            app.LangmuirHinshelwoodRateLawButton.Value = true;
            app.OtherkineticsButton = uiradiobutton(app.KineticsButtonGroup);
            app.OtherkineticsButton.Text = 'Other kinetics';
            app.TextArea = uitextarea(app.KineticsTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'How to specify different kinetics expressions?'; '- Each row corresponds to the rate equation of a different reaction'; '- Concentration of each species has to be expressed as an element of the vector concentration, being consistent with the order used in the stochiometric matrix.'; '- Temperature has to be expressed as T'; ''; 'Example: A + B <-> C  with r_forward = 20*C_A^0.5*C_B^3 and r_reverse = 2*exp(1000/T)*C_C'; 'In the first row of the table you should write:'; '       20 * concentration(1)^0.5 * concentration(2)^3'; 'In the second row you should write:'; '       2 * exp(1000/T)* concentration(3)'; ''};
            app.SpecifythenameofthefunctionEditFieldLabel = uilabel(app.KineticsTab);
            app.SpecifythenameofthefunctionEditFieldLabel.Visible = 'off';
            app.SpecifythenameofthefunctionEditFieldLabel.Text = 'Specify the name of the function ';
            app.SpecifythenameofthefunctionEditField = uieditfield(app.KineticsTab, 'text');
            app.SpecifythenameofthefunctionEditField.Enable = 'off';
            app.SpecifythenameofthefunctionEditField.Visible = 'off';
            app.OtherKineticsFormat = uibuttongroup(app.KineticsTab);
            app.OtherKineticsFormat.SelectionChangedFcn = createCallbackFcn(app, @OtherKineticsFormatSelectionChanged, true);
            app.OtherKineticsFormat.Visible = 'off';
            app.FromfileButton = uiradiobutton(app.OtherKineticsFormat);
            app.FromfileButton.Text = 'From file';
            app.UsingtablebelowButton = uiradiobutton(app.OtherKineticsFormat);
            app.UsingtablebelowButton.Text = 'Using table below';
            app.UsingtablebelowButton.Value = true;
            app.ThermodynamicsTab = uitab(app.TabGroup);
            app.ThermodynamicsTab.Title = 'Thermodynamics';
            app.PropertySelelctionDropDownLabel = uilabel(app.ThermodynamicsTab);
            app.PropertySelelctionDropDownLabel.Text = 'Property Selelction';
            app.PropertySelelctionDropDown = uidropdown(app.ThermodynamicsTab);
            app.PropertySelelctionDropDown.Items = {'', 'Import from Hysys', 'User defined'};
            app.PropertySelelctionDropDown.ValueChangedFcn = createCallbackFcn(app, @PropertySelelctionDropDownValueChanged, true);
            app.PropertySelelctionDropDown.Value = '';
            app.UITable4 = uitable(app.ThermodynamicsTab);
            app.UITable4.ColumnName = {'Cp (J/mol/K)'; 'Mw (g/mol)'};
            app.UITable4.RowName = {};
            app.UITable4.ColumnEditable = true;
            app.UITable4.CellEditCallback = createCallbackFcn(app, @UITable4CellEdit, true);
            app.UITable4.Visible = 'off';
            app.UITable5 = uitable(app.ThermodynamicsTab);
            app.UITable5.ColumnName = {'	ΔHref (J/mol)'};
            app.UITable5.RowName = {};
            app.UITable5.ColumnEditable = true;
            app.UITable5.CellEditCallback = createCallbackFcn(app, @UITable5CellEdit, true);
            app.UITable5.Visible = 'off';
            app.TrefKEditFieldLabel = uilabel(app.ThermodynamicsTab);
            app.TrefKEditFieldLabel.Visible = 'off';
            app.TrefKEditFieldLabel.Text = 'Tref (K)';
            app.TrefKEditField = uieditfield(app.ThermodynamicsTab, 'numeric');
            app.TrefKEditField.ValueDisplayFormat = '%.2f';
            app.TrefKEditField.Visible = 'off';
            app.TrefKEditField.Value = 298.15;
            app.CpoptionDropDownLabel = uilabel(app.ThermodynamicsTab);
            app.CpoptionDropDownLabel.Visible = 'off';
            app.CpoptionDropDownLabel.Text = 'Cp option';
            app.CpoptionDropDown = uidropdown(app.ThermodynamicsTab);
            app.CpoptionDropDown.Items = {'', 'Average', 'Cp = f(T)', 'Cp = f(T,P)'};
            app.CpoptionDropDown.ValueChangedFcn = createCallbackFcn(app, @HysysFileDropDownValueChanged, true);
            app.CpoptionDropDown.Visible = 'off';
            app.CpoptionDropDown.Value = '';
            app.WorkingdirectoryEditFieldLabel = uilabel(app.ThermodynamicsTab);
            app.WorkingdirectoryEditFieldLabel.Visible = 'off';
            app.WorkingdirectoryEditFieldLabel.Text = 'Working directory';
            app.Workingdirectory = uieditfield(app.ThermodynamicsTab, 'text');
            app.Workingdirectory.ValueChangedFcn = createCallbackFcn(app, @HysysFileDropDownValueChanged, true);
            app.Workingdirectory.Visible = 'off';
            app.Workingdirectory.Tooltip = {'Choose the folder where the .xml file used as a data base is found.'};
            app.FilenameEditFieldLabel = uilabel(app.ThermodynamicsTab);
            app.FilenameEditFieldLabel.Visible = 'off';
            app.FilenameEditFieldLabel.Text = 'File name';
            app.Filename = uieditfield(app.ThermodynamicsTab, 'text');
            app.Filename.ValueChangedFcn = createCallbackFcn(app, @HysysFileDropDownValueChanged, true);
            app.Filename.Visible = 'off';
            app.Filename.Tooltip = {'Write the name of the .xml file used as a data base.'; ''; 'WARNING: only write the name, without typing ".xml".'};
            app.StreamnameEditFieldLabel = uilabel(app.ThermodynamicsTab);
            app.StreamnameEditFieldLabel.Visible = 'off';
            app.StreamnameEditFieldLabel.Text = 'Stream name';
            app.Streamname = uieditfield(app.ThermodynamicsTab, 'text');
            app.Streamname.ValueChangedFcn = createCallbackFcn(app, @HysysFileDropDownValueChanged, true);
            app.Streamname.Visible = 'off';
            app.Streamname.Tooltip = {'Name of the stream in ''yourFile.xml'' that you''re going to take the data.'; ''; 'WARNING: make sure it''s an independent stream. Changes will be applied to this stream.'};
            app.HysysFileDropDownLabel = uilabel(app.ThermodynamicsTab);
            app.HysysFileDropDownLabel.Visible = 'off';
            app.HysysFileDropDownLabel.Text = 'Hysys File';
            app.HysysFileDropDown = uidropdown(app.ThermodynamicsTab);
            app.HysysFileDropDown.Items = {'', 'Default (ComponentDataBase.xml)', 'Another file'};
            app.HysysFileDropDown.ValueChangedFcn = createCallbackFcn(app, @HysysFileDropDownValueChanged, true);
            app.HysysFileDropDown.Visible = 'off';
            app.HysysFileDropDown.Value = '';
            app.LoaddatafromHysysButton = uibutton(app.ThermodynamicsTab, 'push');
            app.LoaddatafromHysysButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatafromHysysButtonPushed, true);
            app.LoaddatafromHysysButton.Enable = 'off';
            app.LoaddatafromHysysButton.Visible = 'off';
            app.LoaddatafromHysysButton.Text = 'Load data from Hysys';
            app.UnitconversionhelperButton = uibutton(app.ReactionSysDefinitionUIFigure, 'push');
            app.UnitconversionhelperButton.ButtonPushedFcn = createCallbackFcn(app, @UnitconversionhelperButtonPushed, true);
            app.UnitconversionhelperButton.Icon = 'UnitsLogo.png';
            app.UnitconversionhelperButton.Text = 'Unit conversion helper';
            % Show the figure after all components are created
            app.ReactionSysDefinitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = defineReactionSysApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReactionSysDefinitionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReactionSysDefinitionUIFigure)
        end
    end
end